classdef cavitationMetrics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        LabelNumericEditField  matlab.ui.control.Label            % Points
        Points                 matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField2 matlab.ui.control.Label            % Pulses
        Pulses                 matlab.ui.control.NumericEditField % [-Inf Inf]
        FilterX                matlab.ui.control.UIAxes           % Filtere...
        FreqSpec               matlab.ui.control.UIAxes           % Title
        Run                    matlab.ui.control.Button           % Run
        LabelNumericEditField3 matlab.ui.control.Label            % Avg. Pr...
        AvgProbability         matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField4 matlab.ui.control.Label            % Avg. Pe...
        AvgPersistence         matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField5 matlab.ui.control.Label            % Avg. No...
        AvgNoiseLevel          matlab.ui.control.NumericEditField % [-Inf Inf]
        Quit                   matlab.ui.control.Button           % Quit
        File                   matlab.ui.control.Button           % ▼
        CurrentDir             matlab.ui.control.EditField       
        LabelNumericEditField6 matlab.ui.control.Label            % Start
        Start                  matlab.ui.control.NumericEditField % [-Inf Inf]
    end

    
    properties (Access = public)
        directory;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.directory = '/Users/daniellee/Documents/Internship.../Test';
            app.CurrentDir.Value = app.directory;
            app.Start.Value = 2090;
            app.Points.Value = 5;
            app.Pulses.Value = 60;
        end

        % Quit button pushed function
        function quit(app)
            close all force;
        end

        % Run button pushed function
        function run(app)
            %{
            app.processor = PProcessor(app.Points.Value, app.Pulses.Value, app.FilterX);
            f = run(app.processor);
            app.AvgProbability.Value = f(1);
            app.AvgPersistence.Value = f(2);
            app.AvgNoiseLevel.Value = f(3);
            %}
            
            f = Process(app);
            app.AvgProbability.Value = f(1);
            app.AvgPersistence.Value = f(2);
            app.AvgNoiseLevel.Value = f(3);
            
        end

        % File button pushed function
        function file(app)
            temp = uigetdir;
            if (uigetdir ~= 0)
                app.directory = temp;
                app.CurrentDir.Value = app.directory;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Points';
            app.Points = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Pulses';
            app.Pulses = uieditfield(app.UIFigure, 'numeric');
            app.FilterX = uiaxes(app.UIFigure);
            title(app.FilterX, 'Filtered PCD Signal');
            xlabel(app.FilterX, 'Time');
            ylabel(app.FilterX, 'Signal');
            app.FreqSpec = uiaxes(app.UIFigure);
            title(app.FreqSpec, 'Title');
            xlabel(app.FreqSpec, 'X');
            ylabel(app.FreqSpec, 'Y');
            app.Run = uibutton(app.UIFigure, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @run);
            app.Run.Text = 'Run';
            app.LabelNumericEditField3 = uilabel(app.UIFigure);
            app.LabelNumericEditField3.Text = 'Avg. Probability';
            app.AvgProbability = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField4 = uilabel(app.UIFigure);
            app.LabelNumericEditField4.Text = 'Avg. Persistence';
            app.AvgPersistence = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField5 = uilabel(app.UIFigure);
            app.LabelNumericEditField5.Text = 'Avg. Noise Level';
            app.AvgNoiseLevel = uieditfield(app.UIFigure, 'numeric');
            app.Quit = uibutton(app.UIFigure, 'push');
            app.Quit.ButtonPushedFcn = createCallbackFcn(app, @quit);
            app.Quit.Text = 'Quit';
            app.File = uibutton(app.UIFigure, 'push');
            app.File.ButtonPushedFcn = createCallbackFcn(app, @file);
            app.File.Text = '▼';
            app.CurrentDir = uieditfield(app.UIFigure, 'text');
            app.LabelNumericEditField6 = uilabel(app.UIFigure);
            app.LabelNumericEditField6.Text = 'Start';
            app.Start = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = cavitationMetrics()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end